classdef MotionObjectDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProyekakhirkelaspengolahansinyaldanlayananmultimediaPanel  matlab.ui.container.Panel
        DeteksiObjekBergerakMenggunakanGaussianMixtureModelLabel  matlab.ui.control.Label
        ElyaserBenGuno1806195135TharaAdivaPutriCandra1806187594Label  matlab.ui.control.Label
        ParameterPanel                  matlab.ui.container.Panel
        Numberofgaussianmodesinthemixturemodel345EditFieldLabel  matlab.ui.control.Label
        Numberofgaussianmodesinthemixturemodel345EditField  matlab.ui.control.NumericEditField
        NumberoftrainingframesEditFieldLabel  matlab.ui.control.Label
        NumberoftrainingframesEditField  matlab.ui.control.NumericEditField
        MinimumbackgroundratioEditFieldLabel  matlab.ui.control.Label
        MinimumbackgroundratioEditField  matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel      matlab.ui.control.Label
        LearningRateEditField           matlab.ui.control.NumericEditField
        UploadVideoButton               matlab.ui.control.Button
        VideoteruploadLabel             matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        default3Label                   matlab.ui.control.Label
        default07Label                  matlab.ui.control.Label
        default40Label                  matlab.ui.control.Label
        default0005Label                matlab.ui.control.Label
        acceptedformataviLabel          matlab.ui.control.Label
        ProsesPanel                     matlab.ui.container.Panel
        ProsesButton                    matlab.ui.control.Button
        WaktuprosessekonLabel           matlab.ui.control.Label
        WaktuprosessekonEditField       matlab.ui.control.EditField
        PemutarVideoPanel               matlab.ui.container.Panel
        PlayButton_2                    matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        PlayButton_3                    matlab.ui.control.Button
        HasilGMMMaskingLabel            matlab.ui.control.Label
        MaskingHasilDeteksiLabel        matlab.ui.control.Label
        HasilGMMHasilDeteksiLabel       matlab.ui.control.Label
        UniversitasIndonesiaLabel       matlab.ui.control.Label
        FrameSelectorPanel              matlab.ui.container.Panel
        JumlahFrameEditFieldLabel       matlab.ui.control.Label
        JumlahFrameEditField            matlab.ui.control.EditField
        FrameyangingindilihatEditFieldLabel  matlab.ui.control.Label
        FrameyangingindilihatEditField  matlab.ui.control.NumericEditField
        TampilkanButton                 matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
    end

    
    methods (Access = public)
        
    end
    

    methods (Access = private)

        % Value changed function: 
        % Numberofgaussianmodesinthemixturemodel345EditField
        function Numberofgaussianmodesinthemixturemodel345EditFieldValueChanged(app, event)
            numGauss = app.Numberofgaussianmodesinthemixturemodel345EditField.Value;
            assignin('base','numGauss',numGauss');
        end

        % Value changed function: NumberoftrainingframesEditField
        function NumberoftrainingframesEditFieldValueChanged(app, event)
            numTFrames = app.NumberoftrainingframesEditField.Value;
            assignin('base','numTFrames',numTFrames);
        end

        % Value changed function: MinimumbackgroundratioEditField
        function MinimumbackgroundratioEditFieldValueChanged(app, event)
            minBGRatio = app.MinimumbackgroundratioEditField.Value;
            assignin('base','minBGRatio',minBGRatio);
        end

        % Value changed function: LearningRateEditField
        function LearningRateEditFieldValueChanged(app, event)
            learnRate = app.LearningRateEditField.Value;
            assignin('base','learnRate',learnRate);
        end

        % Button pushed function: ProsesButton
        function ProsesButtonPushed(app, event)
            app.WaktuprosessekonEditField.Value = 'processing...';
            tic
            run('DOBmGMM.m');
            waktuProses = toc
            waktuProsesText = string(waktuProses);
            app.WaktuprosessekonEditField.Value = waktuProsesText;
            numFrames = evalin('base','numFrames');
            app.JumlahFrameEditField.Value = string(numFrames);
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed2(app, event)
            numFrames = evalin('base','numFrames');
            reader1 = VideoReader('frame.avi');
            reader2 = VideoReader('mask.avi');
            reader3 = VideoReader('afterGMM.avi');
            
            videoPlayer = vision.VideoPlayer('Position', [700, 200, 700, 400]);
            maskPlayer = vision.VideoPlayer('Position', [0, 200, 700, 400]);
        
            for i=1:1:numFrames-1
                frame = readFrame(reader1);
                mask = readFrame(reader2);
        
                maskPlayer.step(mask);
                videoPlayer.step(frame);
                pause(1/30);
            end
        end

        % Button pushed function: UploadVideoButton
        function UploadVideoButtonPushed(app, event)
            filename = uigetfile('*.avi');
            assignin('base','filename',filename);
            app.EditField.Value = filename;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            numFrames = evalin('base','numFrames');
            reader1 = VideoReader('frame.avi');
            reader2 = VideoReader('mask.avi');
            reader3 = VideoReader('afterGMM.avi');
            
            maskPlayer = vision.VideoPlayer('Position', [700, 200, 700, 400]);
            GMMPlayer = vision.VideoPlayer('Position', [0, 200, 700, 400]);
        
            for i=1:1:numFrames-1
                GMM = readFrame(reader3);
                mask = readFrame(reader2);
        
                GMMPlayer.step(GMM);
                maskPlayer.step(mask);
                pause(1/30);
            end
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
            numFrames = evalin('base','numFrames');
            reader1 = VideoReader('frame.avi');
            reader2 = VideoReader('mask.avi');
            reader3 = VideoReader('afterGMM.avi');
            
            videoPlayer = vision.VideoPlayer('Position', [700, 200, 700, 400]);
            GMMPlayer = vision.VideoPlayer('Position', [0, 200, 700, 400]);
        
            for i=1:1:numFrames-1
                GMM = readFrame(reader3);
                frame = readFrame(reader1);
        
                GMMPlayer.step(GMM);
                videoPlayer.step(frame);
                pause(1/30);
            end
        end

        % Button pushed function: TampilkanButton
        function TampilkanButtonPushed(app, event)
            numfr = app.FrameyangingindilihatEditField.Value;
            numFrames = evalin('base','numFrames');
            
            if numfr > numFrames
                numfr = numFrames
            end
            
            asli = VideoReader('videotest1.avi');
            afGMM = VideoReader('afterGMM.avi');
            fr = VideoReader('frame.avi');
            ms = VideoReader('mask.avi');
            
            figure('units','normalized','outerposition',[0 0 1 1])
            subplot(2,2,1);
            imshow(read(asli,numfr)); title('video asli');
            imwrite(read(asli,numfr),'1. video asli.png');
            subplot(2,2,2);
            imshow(read(fr,numfr)); title('video hasil deteksi');
            imwrite(read(fr,numfr),'4. video hasil deteksi.png');
            subplot(2,2,3);
            imshow(read(afGMM,numfr)); title('deteksi GMM');
            imwrite(read(afGMM,numfr),'2. deteksi GMM.png');
            subplot(2,2,4);
            imshow(read(ms,numfr)); title('mask (deteksi GMM setelah operasi morfologi');
            imwrite(read(ms,numfr),'3. setelah morfologi.png');
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            f = msgbox({'petunjuk penggunaan:',...
                '1. upload video yang ingin diproses (format: *.avi)',...
                '2. masukkan parameter inputnya:',...
                '   jumlah GMM, defaultnya 3, 4 atau 5',...
                '   jumlah training frames, defaultnya 40',...
                '   porsi minimum background, rentangnya 0-1, defaultnya 0,7',...
                '   learning rate, rentangnya 0-1, defaultnya 0,005',...
                '3. lalu, tekan tombol proses, tunggu',...
                '4. setelah selesai, akan muncul waktu komputasi dan',...
                '   jumlah frame yang ada',...
                '5. user bisa menampilkan video hasil proses,',...
                '   hanya dua video yang bisa ditampilkan dalam ',...
                '   satu layar',...
                '6. user juga dapat meninjau frame tertentu',...
                '   melalui frame selector'}); 
            set(f, 'position', [300 100 350 300]); %makes box bigger
            ah = get( f, 'CurrentAxes' );
            ch = get( ah, 'Children' );
            set( ch, 'FontSize', 12 ); %makes text bigger
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ProyekakhirkelaspengolahansinyaldanlayananmultimediaPanel = uipanel(app.UIFigure);
            app.ProyekakhirkelaspengolahansinyaldanlayananmultimediaPanel.Title = 'Proyek akhir kelas pengolahan sinyal dan layanan multimedia  ';
            app.DeteksiObjekBergerakMenggunakanGaussianMixtureModelLabel = uilabel(app.ProyekakhirkelaspengolahansinyaldanlayananmultimediaPanel);
            app.DeteksiObjekBergerakMenggunakanGaussianMixtureModelLabel.Text = {'Deteksi Objek Bergerak Menggunakan Gaussian '; 'Mixture Model'};
            app.ElyaserBenGuno1806195135TharaAdivaPutriCandra1806187594Label = uilabel(app.ProyekakhirkelaspengolahansinyaldanlayananmultimediaPanel);
            app.ElyaserBenGuno1806195135TharaAdivaPutriCandra1806187594Label.Text = 'Elyaser Ben Guno (1806195135) & Thara Adiva Putri Candra (1806187594)';
            app.ParameterPanel = uipanel(app.UIFigure);
            app.ParameterPanel.Title = 'Parameter';
            app.Numberofgaussianmodesinthemixturemodel345EditFieldLabel = uilabel(app.ParameterPanel);
            app.Numberofgaussianmodesinthemixturemodel345EditFieldLabel.Text = {'Number of gaussian'; 'modes in the mixture'; 'model (3 / 4 / 5)'};
            app.Numberofgaussianmodesinthemixturemodel345EditField = uieditfield(app.ParameterPanel, 'numeric');
            app.Numberofgaussianmodesinthemixturemodel345EditField.ValueChangedFcn = createCallbackFcn(app, @Numberofgaussianmodesinthemixturemodel345EditFieldValueChanged, true);
            app.NumberoftrainingframesEditFieldLabel = uilabel(app.ParameterPanel);
            app.NumberoftrainingframesEditFieldLabel.Text = {'Number of'; 'training'; 'frames'};
            app.NumberoftrainingframesEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.NumberoftrainingframesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoftrainingframesEditFieldValueChanged, true);
            app.MinimumbackgroundratioEditFieldLabel = uilabel(app.ParameterPanel);
            app.MinimumbackgroundratioEditFieldLabel.Text = {'Minimum'; 'background'; 'ratio'};
            app.MinimumbackgroundratioEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.MinimumbackgroundratioEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumbackgroundratioEditFieldValueChanged, true);
            app.LearningRateEditFieldLabel = uilabel(app.ParameterPanel);
            app.LearningRateEditFieldLabel.Text = {'Learning'; 'Rate'};
            app.LearningRateEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.LearningRateEditField.ValueChangedFcn = createCallbackFcn(app, @LearningRateEditFieldValueChanged, true);
            app.UploadVideoButton = uibutton(app.ParameterPanel, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'Upload Video';
            app.VideoteruploadLabel = uilabel(app.ParameterPanel);
            app.VideoteruploadLabel.Text = 'Video terupload:';
            app.EditField = uieditfield(app.ParameterPanel, 'text');
            app.Label = uilabel(app.ParameterPanel);
            app.Label.Text = '(0 - 1)';
            app.Label_2 = uilabel(app.ParameterPanel);
            app.Label_2.Text = '(0 - 1)';
            app.default3Label = uilabel(app.ParameterPanel);
            app.default3Label.Text = 'default: 3';
            app.default07Label = uilabel(app.ParameterPanel);
            app.default07Label.Text = 'default: 0,7';
            app.default40Label = uilabel(app.ParameterPanel);
            app.default40Label.Text = 'default: 40';
            app.default0005Label = uilabel(app.ParameterPanel);
            app.default0005Label.Text = 'default: 0,005';
            app.acceptedformataviLabel = uilabel(app.ParameterPanel);
            app.acceptedformataviLabel.Text = 'accepted format: *.avi';
            app.ProsesPanel = uipanel(app.UIFigure);
            app.ProsesPanel.Title = 'Proses';
            app.ProsesButton = uibutton(app.ProsesPanel, 'push');
            app.ProsesButton.ButtonPushedFcn = createCallbackFcn(app, @ProsesButtonPushed, true);
            app.ProsesButton.Text = 'Proses';
            app.WaktuprosessekonLabel = uilabel(app.ProsesPanel);
            app.WaktuprosessekonLabel.Text = 'Waktu proses (sekon)';
            app.WaktuprosessekonEditField = uieditfield(app.ProsesPanel, 'text');
            app.PemutarVideoPanel = uipanel(app.UIFigure);
            app.PemutarVideoPanel.Title = 'Pemutar Video';
            app.PlayButton_2 = uibutton(app.PemutarVideoPanel, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed2, true);
            app.PlayButton_2.Text = 'Play';
            app.PlayButton = uibutton(app.PemutarVideoPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PlayButton_3 = uibutton(app.PemutarVideoPanel, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Text = 'Play';
            app.HasilGMMMaskingLabel = uilabel(app.PemutarVideoPanel);
            app.HasilGMMMaskingLabel.Text = {'Hasil GMM &'; 'Masking'};
            app.MaskingHasilDeteksiLabel = uilabel(app.PemutarVideoPanel);
            app.MaskingHasilDeteksiLabel.Text = {'Masking &'; 'Hasil Deteksi'};
            app.HasilGMMHasilDeteksiLabel = uilabel(app.PemutarVideoPanel);
            app.HasilGMMHasilDeteksiLabel.Text = {'Hasil GMM &'; 'Hasil Deteksi'};
            app.UniversitasIndonesiaLabel = uilabel(app.UIFigure);
            app.UniversitasIndonesiaLabel.Text = 'Universitas Indonesia';
            app.FrameSelectorPanel = uipanel(app.UIFigure);
            app.FrameSelectorPanel.Title = 'Frame Selector';
            app.JumlahFrameEditFieldLabel = uilabel(app.FrameSelectorPanel);
            app.JumlahFrameEditFieldLabel.Text = 'Jumlah Frame';
            app.JumlahFrameEditField = uieditfield(app.FrameSelectorPanel, 'text');
            app.FrameyangingindilihatEditFieldLabel = uilabel(app.FrameSelectorPanel);
            app.FrameyangingindilihatEditFieldLabel.Text = {'Frame yang'; 'ingin dilihat'};
            app.FrameyangingindilihatEditField = uieditfield(app.FrameSelectorPanel, 'numeric');
            app.TampilkanButton = uibutton(app.FrameSelectorPanel, 'push');
            app.TampilkanButton.ButtonPushedFcn = createCallbackFcn(app, @TampilkanButtonPushed, true);
            app.TampilkanButton.Text = 'Tampilkan';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MotionObjectDetector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end